classdef ParamPoly3_Lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        paramPoly3parametersPanel   matlab.ui.container.Panel
        aUSliderLabel               matlab.ui.control.Label
        aUSlider                    matlab.ui.control.Slider
        bUSliderLabel               matlab.ui.control.Label
        bUSlider                    matlab.ui.control.Slider
        cUSliderLabel               matlab.ui.control.Label
        cUSlider                    matlab.ui.control.Slider
        dUSliderLabel               matlab.ui.control.Label
        dUSlider                    matlab.ui.control.Slider
        aVSliderLabel               matlab.ui.control.Label
        aVSlider                    matlab.ui.control.Slider
        bVSliderLabel               matlab.ui.control.Label
        bVSlider                    matlab.ui.control.Slider
        cVSliderLabel               matlab.ui.control.Label
        cVSlider                    matlab.ui.control.Slider
        dVSliderLabel               matlab.ui.control.Label
        dVSlider                    matlab.ui.control.Slider
        ResetButton                 matlab.ui.control.Button
        ResizeButton                matlab.ui.control.Button
        InfoButton                  matlab.ui.control.Button
        startXEditFieldLabel        matlab.ui.control.Label
        startXEditField             matlab.ui.control.NumericEditField
        startYEditFieldLabel        matlab.ui.control.Label
        startYEditField             matlab.ui.control.NumericEditField
        startHeadingEditFieldLabel  matlab.ui.control.Label
        startHeadingEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        %by default
        pp3Parameters = struct('aU',0,'bU',1,'cU',-0.124,'dU',0.00957, ...
            'aV',0,'bV',0,'cV',0.186,'dV',-0.0112,'hdg',5.972,'startX',16,'startY',-4,'length',7.37);
        pp3Points = struct('x',[],'y',[]);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg)
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
            hold(app.UIAxes,"on");
            grid(app.UIAxes,"on");
            minX = min(app.pp3Points.x) - 2;
            maxX = max(app.pp3Points.x) + 2;
            minY = min(app.pp3Points.y) - 2;
            maxY = max(app.pp3Points.y) + 2;
            
            app.UIAxes.XLim = [minX,maxX];
            app.UIAxes.YLim = [minY,maxY];
        end

        % Callback function
        function ChooseModeButtonGroupSelectionChanged(app, event)
            if(app.SliderButton.Value == true)
                app.paramPoly3parametersPanel.Visible = true;
                app.manualPanel.Visible = false;
            else
                app.paramPoly3parametersPanel.Visible = false;
                app.manualPanel.Visible = true;
            end
        end

        % Value changing function: aUSlider
        function aUSliderValueChanging(app, event)
            app.pp3Parameters.aU = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: bUSlider
        function bUSliderValueChanging(app, event)
            app.pp3Parameters.bU = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: cUSlider
        function cUSliderValueChanging(app, event)
            app.pp3Parameters.cU = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: dUSlider
        function dUSliderValueChanging(app, event)
            app.pp3Parameters.dU = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: aVSlider
        function aVSliderValueChanging(app, event)
            app.pp3Parameters.aV= event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: bVSlider
        function bVSliderValueChanging(app, event)
            app.pp3Parameters.bV = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: cVSlider
        function cVSliderValueChanging(app, event)
            app.pp3Parameters.cV = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changing function: dVSlider
        function dVSliderValueChanging(app, event)
            app.pp3Parameters.dV = event.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                    app.pp3Parameters = struct('aU',0,'bU',1,'cU',-0.124,'dU',0.00957, ...
            'aV',0,'bV',0,'cV',0.186,'dV',-0.0112,'hdg',5.972,'startX',16,'startY',-4,'length',7.37);
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
            
            minX = min(app.pp3Points.x) - 2;
            maxX = max(app.pp3Points.x) + 2;
            minY = min(app.pp3Points.y) - 2;
            maxY = max(app.pp3Points.y) + 2;
            
            app.UIAxes.XLim = [minX,maxX];
            app.UIAxes.YLim = [minY,maxY];
            
            app.aUSlider.Value = app.pp3Parameters.aU;
            app.bUSlider.Value = app.pp3Parameters.bU;
            app.cUSlider.Value = app.pp3Parameters.cU;
            app.dUSlider.Value = app.pp3Parameters.dU;
            app.aVSlider.Value = app.pp3Parameters.aV;
            app.bVSlider.Value = app.pp3Parameters.bV;
            app.cVSlider.Value = app.pp3Parameters.cV;
            app.dVSlider.Value = app.pp3Parameters.dV;
            app.startXEditField.Value = app.pp3Parameters.startX;
            app.startYEditField.Value = app.pp3Parameters.startY;
            app.startHeadingEditField.Value = app.pp3Parameters.hdg;
            
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            minX = min(app.pp3Points.x) - 2;
            maxX = max(app.pp3Points.x) + 2;
            minY = min(app.pp3Points.y) - 2;
            maxY = max(app.pp3Points.y) + 2;
            
            app.UIAxes.XLim = [minX,maxX];
            app.UIAxes.YLim = [minY,maxY];
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            infoBox;
        end

        % Value changed function: startXEditField
        function startXEditFieldValueChanged(app, event)
            app.pp3Parameters.startX = app.startXEditField.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changed function: startYEditField
        function startYEditFieldValueChanged(app, event)
            app.pp3Parameters.startY = app.startYEditField.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end

        % Value changed function: startHeadingEditField
        function startHeadingEditFieldValueChanged(app, event)
            app.pp3Parameters.hdg = app.startHeadingEditField.Value;
            app.pp3Points = calculatePP3points(app.pp3Parameters);
            app.UIAxes.cla;
            plot(app.UIAxes,app.pp3Points.x,app.pp3Points.y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [15 30];
            app.UIAxes.YLim = [-10 5];
            app.paramPoly3parametersPanel = uipanel(app.UIFigure);
            app.paramPoly3parametersPanel.Title = 'paramPoly3 parameters';
            app.aUSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.aUSliderLabel.Text = 'aU';
            app.aUSlider = uislider(app.paramPoly3parametersPanel);
            app.aUSlider.Limits = [-2 2];
            app.aUSlider.ValueChangingFcn = createCallbackFcn(app, @aUSliderValueChanging, true);
            app.bUSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.bUSliderLabel.Text = 'bU';
            app.bUSlider = uislider(app.paramPoly3parametersPanel);
            app.bUSlider.Limits = [-2 2];
            app.bUSlider.ValueChangingFcn = createCallbackFcn(app, @bUSliderValueChanging, true);
            app.bUSlider.Value = 1;
            app.cUSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.cUSliderLabel.Text = 'cU';
            app.cUSlider = uislider(app.paramPoly3parametersPanel);
            app.cUSlider.Limits = [-2 2];
            app.cUSlider.ValueChangingFcn = createCallbackFcn(app, @cUSliderValueChanging, true);
            app.cUSlider.Value = -0.124;
            app.dUSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.dUSliderLabel.Text = 'dU';
            app.dUSlider = uislider(app.paramPoly3parametersPanel);
            app.dUSlider.Limits = [-2 2];
            app.dUSlider.ValueChangingFcn = createCallbackFcn(app, @dUSliderValueChanging, true);
            app.dUSlider.Value = -0.124;
            app.aVSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.aVSliderLabel.Text = 'aV';
            app.aVSlider = uislider(app.paramPoly3parametersPanel);
            app.aVSlider.Limits = [-2 2];
            app.aVSlider.ValueChangingFcn = createCallbackFcn(app, @aVSliderValueChanging, true);
            app.bVSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.bVSliderLabel.Text = 'bV';
            app.bVSlider = uislider(app.paramPoly3parametersPanel);
            app.bVSlider.Limits = [-2 2];
            app.bVSlider.ValueChangingFcn = createCallbackFcn(app, @bVSliderValueChanging, true);
            app.cVSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.cVSliderLabel.Text = 'cV';
            app.cVSlider = uislider(app.paramPoly3parametersPanel);
            app.cVSlider.Limits = [-2 2];
            app.cVSlider.ValueChangingFcn = createCallbackFcn(app, @cVSliderValueChanging, true);
            app.cVSlider.Value = 0.186;
            app.dVSliderLabel = uilabel(app.paramPoly3parametersPanel);
            app.dVSliderLabel.Text = 'dV';
            app.dVSlider = uislider(app.paramPoly3parametersPanel);
            app.dVSlider.Limits = [-2 2];
            app.dVSlider.ValueChangingFcn = createCallbackFcn(app, @dVSliderValueChanging, true);
            app.dVSlider.Value = -0.0112;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.startXEditFieldLabel = uilabel(app.UIFigure);
            app.startXEditFieldLabel.Text = 'startX';
            app.startXEditField = uieditfield(app.UIFigure, 'numeric');
            app.startXEditField.ValueChangedFcn = createCallbackFcn(app, @startXEditFieldValueChanged, true);
            app.startXEditField.Value = 16;
            app.startYEditFieldLabel = uilabel(app.UIFigure);
            app.startYEditFieldLabel.Text = 'startY';
            app.startYEditField = uieditfield(app.UIFigure, 'numeric');
            app.startYEditField.ValueChangedFcn = createCallbackFcn(app, @startYEditFieldValueChanged, true);
            app.startYEditField.Value = -4;
            app.startHeadingEditFieldLabel = uilabel(app.UIFigure);
            app.startHeadingEditFieldLabel.Text = 'startHeading';
            app.startHeadingEditField = uieditfield(app.UIFigure, 'numeric');
            app.startHeadingEditField.ValueChangedFcn = createCallbackFcn(app, @startHeadingEditFieldValueChanged, true);
            app.startHeadingEditField.Value = 5.971;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamPoly3_Lab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end